classdef SimilarityMatriceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimilarityMatriceMakerTab      matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        OutputsPanel                   matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ImportMatriceButton            matlab.ui.control.Button
        DownloadMatriceButton          matlab.ui.control.Button
        SignalUIAxes                   matlab.ui.control.UIAxes
        SimilarityMatriceUIAxes        matlab.ui.control.UIAxes
        InputsPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        BPMValueEditFieldLabel         matlab.ui.control.Label
        StartingPointSliderLabel       matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        StartingPointSlider            matlab.ui.control.Slider
        ListenSignalButton             matlab.ui.control.Button
        BPMValueEditField              matlab.ui.control.NumericEditField
        AudioFileNameEditField         matlab.ui.control.EditField
        AudioFileNameEditFieldLabel    matlab.ui.control.Label
        SimilarityMatrixButton         matlab.ui.control.Button
        CopyBPMButton                  matlab.ui.control.Button
        SongsBPMButton                 matlab.ui.control.Button
        ImportSignalButton             matlab.ui.control.Button
        ChorusDetectorTab              matlab.ui.container.Tab
        GridLayout_2                   matlab.ui.container.GridLayout
        OutputsPanel_2                 matlab.ui.container.Panel
        GridLayout3_2                  matlab.ui.container.GridLayout
        ExpandButton                   matlab.ui.control.Button
        DownloadMatriceButton_2        matlab.ui.control.Button
        SimilarityMatriceUIAxes_2      matlab.ui.control.UIAxes
        InputsPanel_2                  matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        MedianFilterIndexLabel         matlab.ui.control.Label
        BinaryIndexLabel               matlab.ui.control.Label
        BinaryIndexSlider              matlab.ui.control.Slider
        ImportImageButton              matlab.ui.control.Button
        ImageFileNameEditField         matlab.ui.control.EditField
        AudioFileNameEditField_2Label  matlab.ui.control.Label
        MedianFilterIndexSpinner       matlab.ui.control.Spinner
        ShowColorCheckBox              matlab.ui.control.CheckBox
        DisplayContourButton           matlab.ui.control.Button
        MorphologicallyImageButtonGroup  matlab.ui.container.ButtonGroup
        CloseButton                    matlab.ui.control.RadioButton
        OpenButton                     matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Path % Path of the music
        Filename % Filename of the music
        Path_2 % Path of the Similarity Matrice
        Filename_2 % Filemname of the Similarity Matrice
        Signal % Signal of the music
        Fs % Sampling Frequency of the music
        Time % Description
        NewSignal % Description
        BPM % BPM of the music
        Max % Description
        MatSimi % Description
        Binary % Description
        BinaryValue
        Median % Description
        MedianValue % Description
        Morph % Description
        MorphValue % Description
        Contour % Description
        MatSimiColored % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportSignalButton
        function ImportSignalButtonPushed(app, event)
            %% Resetting main graph
            clear app.MatSimi app.BPM app.NewSignal app.Signal app.MorphValue app.MedianValue;
            cla(app.SignalUIAxes);
            cla(app.SimilarityMatriceUIAxes);
            cla(app.SimilarityMatriceUIAxes_2);

            %% Component Visibility
            app.SimilarityMatriceUIAxes_2.Visible = 'off';
            app.BinaryIndexSlider.Visible = 'off';
            app.BinaryIndexLabel.Visible = 'off';
            app.MorphologicallyImageButtonGroup.Visible = 'off';
            app.DisplayContourButton.Visible = 'off';
            app.MedianFilterIndexLabel.Visible = 'off';
            app.MedianFilterIndexSpinner.Visible = 'off';
            app.ShowColorCheckBox.Visible = 'off';
            app.DownloadMatriceButton_2.Visible = 'off';
            app.ExpandButton.Visible = 'off';
            app.ImageFileNameEditField.Value = '';
            
            %% Audio File Reading and Opening
            addpath('Personal Functions/Periodicity Transform/','Drums Only/','Personal Functions/Audio Processing/')
            [filename, path] = uigetfile("Drums Only/*.mp3");
            app.Path = path;
            app.Filename = filename;
            app.AudioFileNameEditField.Value = erase(string(app.Filename),'.mp3');
            [y, Fe] = audioread(append(app.Path,app.Filename));
            app.Fs = Fe;
            app.Signal = selection(y, 4e-3);

            %% Audio File display
            app.Max = max(abs(app.Signal));
            app.NewSignal = app.Signal(1:5*app.Fs);
            app.Time = (0:length(app.NewSignal)-1)./app.Fs;
            plot(app.SignalUIAxes, app.Time, app.NewSignal);
            xlim(app.SignalUIAxes,[0 5]);
            ylim(app.SignalUIAxes,[-app.Max app.Max]);

            %% Component Visibility
            app.SignalUIAxes.Visible = 'on';
            app.ListenSignalButton.Visible = 'on';
            app.SimilarityMatrixButton.Visible = 'on';
            app.CopyBPMButton.Visible = 'on';
            app.SongsBPMButton.Visible = 'on';
            app.BPMValueEditField.Visible = 'on';
            app.BPMValueEditFieldLabel.Visible = 'on';
            app.StartingPointSlider.Visible = 'on';
            app.StartingPointSliderLabel.Visible = 'on';
            app.DownloadMatriceButton.Visible = 'on';
            app.BPMValueEditField.Value = 0;
        end

        % Button pushed function: ListenSignalButton
        function ListenSignalButtonPushed(app, event)
            if ~ismissing(app.NewSignal)
                %% Play sound's part
                sound(app.NewSignal, app.Fs);
            end
        end

        % Value changing function: StartingPointSlider
        function StartingPointSliderValueChanging(app, event)
            %% Change graph value
            changingValue = event.Value;
            app.NewSignal = app.Signal(changingValue*app.Fs+1:(changingValue+5)*app.Fs);
            app.Time = ((0:length(app.NewSignal)-1)./app.Fs)+changingValue;

            %% Audio File display
            plot(app.SignalUIAxes, app.Time, app.NewSignal);
            ylim(app.SignalUIAxes,[-app.Max app.Max]);
            xlim(app.SignalUIAxes,[changingValue 5+changingValue]);
        end

        % Button pushed function: SongsBPMButton
        function SongsBPMButtonPushed(app, event)
            %% Waiting image display
            app.Image.Visible = 'on';
            pause(0.1);

            %% Calculates song's BPM
            addpath('Personal Functions/Periodicity Transform/','Drums Only/','Personal Functions/Audio Processing/')
            if ~ismissing(app.NewSignal)
                app.BPM = bpmTracker2(app.NewSignal, app.Fs, 500);
                app.BPMValueEditField.Value = app.BPM;
                figure(app.UIFigure);
            end

            %% Waiting image display
            app.Image.Visible = 'off';
        end

        % Button pushed function: SimilarityMatrixButton
        function SimilarityMatrixButtonPushed(app, event)
            %% Waiting image display
            app.Image.Visible = 'on';
            clear app.SimilarityMatriceUIAxes;
            clear app.SimilarityMatriceUIAxes_2;
            pause(0.1);
            
            %% Calculates Similarity Matrice
            if ~ismissing(app.Signal)
                if ~ismissing(app.BPM)
                    seg = segmentation(app.Signal, app.Fs, app.BPM);
                    app.MatSimi = correlationHalfNormalize(seg);
                    imagesc(app.SimilarityMatriceUIAxes,'CData',app.MatSimi);
                    app.SimilarityMatriceUIAxes.XAxis.Visible = 'off';
                    app.SimilarityMatriceUIAxes.YAxis.Visible = 'off';
                    ylim(app.SimilarityMatriceUIAxes,'tight');xlim(app.SimilarityMatriceUIAxes,'tight');
                end
            end

            %% Waiting image & Similarity Matrice display
            app.Image.Visible = 'off';
            app.SimilarityMatriceUIAxes.Visible = 'on';
        end

        % Button pushed function: CopyBPMButton
        function CopyBPMButtonPushed(app, event)
            if ~ismissing(app.BPM)
                clipboard('copy', app.BPM);
            end
        end

        % Value changed function: BPMValueEditField
        function BPMValueEditFieldValueChanged(app, event)
            app.BPM = app.BPMValueEditField.Value;
        end

        % Button pushed function: DownloadMatriceButton
        function DownloadMatriceButtonPushed(app, event)
            %% Waiting image display
            app.Image.Visible = 'on';
            pause(0.1);
            
            %% Download Similarity Matrice
            if ~ismissing(app.MatSimi)
                name = erase(string(app.Filename),'.mp3');
                groupe = extractBefore(name, " -");
                mkdir('Similarity Matrice/',groupe);
                dossier = append('Similarity Matrice/',groupe,'/');
                writematrix(app.MatSimi, append(dossier,name,'.xlsx'));
                imwrite(app.MatSimi, append(dossier,name,'.png'));
            end 

            %% Waiting image display
            app.Image.Visible = 'off';
        end

        % Button pushed function: ImportMatriceButton
        function ImportMatriceButtonPushed(app, event)
            %% Resetting all graphs
            clear app.MatSimi app.BPM app.NewSignal app.Signal app.MorphValue app.MedianValue;
            cla(app.SignalUIAxes);
            cla(app.SimilarityMatriceUIAxes);
            cla(app.SimilarityMatriceUIAxes_2);

            %% Component Visibility
            app.SimilarityMatriceUIAxes_2.Visible = 'off';
            app.BinaryIndexSlider.Visible = 'off';
            app.BinaryIndexLabel.Visible = 'off';
            app.MorphologicallyImageButtonGroup.Visible = 'off';
            app.DisplayContourButton.Visible = 'off';
            app.MedianFilterIndexLabel.Visible = 'off';
            app.MedianFilterIndexSpinner.Visible = 'off';
            app.ShowColorCheckBox.Visible = 'off';
            app.DownloadMatriceButton_2.Visible = 'off';
            app.ExpandButton.Visible = 'off';
            app.SignalUIAxes.Visible = 'on';
            app.ListenSignalButton.Visible = 'on';
            app.SimilarityMatrixButton.Visible = 'on';
            app.CopyBPMButton.Visible = 'on';
            app.SongsBPMButton.Visible = 'on';
            app.BPMValueEditField.Visible = 'on';
            app.BPMValueEditFieldLabel.Visible = 'on';
            app.StartingPointSlider.Visible = 'on';
            app.StartingPointSliderLabel.Visible = 'on';
            app.DownloadMatriceButton.Visible = 'on';
            app.ImageFileNameEditField.Value = '';
            
            %% Similarity Matrice Reading and Opening
            addpath('Similarity Matrice');
            [filename, path] = uigetfile('Similarity Matrice/*.xlsx');
            app.MatSimi = readmatrix(append(path, filename));

            %% Similarity Matrice  display
            imagesc(app.SimilarityMatriceUIAxes,'CData',app.MatSimi);
            app.SimilarityMatriceUIAxes.XAxis.Visible = 'off';
            app.SimilarityMatriceUIAxes.YAxis.Visible = 'off';
            ylim(app.SimilarityMatriceUIAxes,'tight');xlim(app.SimilarityMatriceUIAxes,'tight');
            
            %% Writing Similarity Matrice information
            if isfile(append("Drums Only/",erase(string(filename),'.xlsx'),'.mp3'))
                app.Path = "Drums Only/";
                app.Filename = append(erase(string(filename),'.xlsx'),'.mp3');
                app.AudioFileNameEditField.Value = erase(string(app.Filename),'.mp3');
                
                %% Audio File display
                [y, Fe] = audioread(append(app.Path,app.Filename));
                app.Fs = Fe;
                app.Signal = selection(y, 4e-3);
                app.Max = max(abs(app.Signal));
                app.NewSignal = app.Signal(1:5*app.Fs);
                app.Time = (0:length(app.NewSignal)-1)./app.Fs;
                plot(app.SignalUIAxes, app.Time, app.NewSignal);
                xlim(app.SignalUIAxes,[0 5]);
                ylim(app.SignalUIAxes,[-app.Max app.Max]);
            else
                app.Path = '';
                app.Filename = '';
                app.AudioFileNameEditField.Value = '';
            end

            app.BPMValueEditField.Value = 0;
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            %% Resetting all graphs
            clear app.MatSimi app.BPM app.NewSignal app.Signal app.MorphValue app.MedianValue;
            cla(app.SignalUIAxes);
            cla(app.SimilarityMatriceUIAxes);
            cla(app.SimilarityMatriceUIAxes_2);

            %% Component Visibility
            app.SignalUIAxes.Visible = 'off';
            app.SimilarityMatriceUIAxes.Visible = 'off';
            app.ListenSignalButton.Visible = 'off';
            app.SimilarityMatrixButton.Visible = 'off';
            app.CopyBPMButton.Visible = 'off';
            app.SongsBPMButton.Visible = 'off';
            app.BPMValueEditField.Visible = 'off';
            app.BPMValueEditFieldLabel.Visible = 'off';
            app.StartingPointSlider.Visible = 'off';
            app.StartingPointSliderLabel.Visible = 'off';
            app.DownloadMatriceButton.Visible = 'off';
            app.ShowColorCheckBox.Visible = 'off';
            app.ShowColorCheckBox.Value = 0;
            app.Path = '';
            app.Filename = '';
            app.AudioFileNameEditField.Value = '';
            
            %% Image Reading and Opening
            [app.Filename_2, app.Path_2] = uigetfile("Similarity Matrice/*.png");
            app.ImageFileNameEditField.Value = erase(string(app.Filename_2),'.png');
            app.MatSimi = imread(append(app.Path_2, app.Filename_2));

            %% Image display
            imagesc(app.SimilarityMatriceUIAxes_2,'CData',app.MatSimi);
            ylim(app.SimilarityMatriceUIAxes_2,'tight');xlim(app.SimilarityMatriceUIAxes_2,'tight');

            %% Filter display
            app.BinaryIndexSlider.Visible = 'on';
            app.BinaryIndexLabel.Visible = 'on';
            app.ExpandButton.Visible = 'on';
        end

        % Value changed function: MedianFilterIndexSpinner
        function MedianFilterIndexSpinnerValueChanged(app, event)
            %% Resetting main graph
            cla(app.SimilarityMatriceUIAxes_2);
            app.ShowColorCheckBox.Visible = 'off';

            %% Median filter application
            app.MedianValue = app.MedianFilterIndexSpinner.Value;
            app.Median = medfilt2(app.Binary, [app.MedianValue app.MedianValue]);

            %% Image filtered display
            if ~ismissing(app.MorphValue)
                MorphologicallyImageButtonGroupSelectionChanged(app, event);
            else
                %% Image display
                imagesc(app.SimilarityMatriceUIAxes_2,'CData',app.Median);
                ylim(app.SimilarityMatriceUIAxes_2,'tight');
                xlim(app.SimilarityMatriceUIAxes_2,'tight');
                
                %% Filter display
                app.MorphologicallyImageButtonGroup.Visible = 'on';
            end
        end

        % Selection changed function: MorphologicallyImageButtonGroup
        function MorphologicallyImageButtonGroupSelectionChanged(app, event)
            %% Resetting main graph
            cla(app.SimilarityMatriceUIAxes_2);
            app.ShowColorCheckBox.Visible = 'off';
            
            %% Morphologically filter application
            app.MorphValue = app.MorphologicallyImageButtonGroup.SelectedObject;
            
            %% Image filtered display
            if app.MorphValue == app.CloseButton
                app.Morph = imclose(app.Median, strel('square',app.MedianValue));
            else
                app.Morph = imopen(app.Median, strel('square',app.MedianValue));
            end
            imagesc(app.SimilarityMatriceUIAxes_2,'CData',app.Morph);
            ylim(app.SimilarityMatriceUIAxes_2,'tight');
            xlim(app.SimilarityMatriceUIAxes_2,'tight');

            %% Filter display
            app.DisplayContourButton.Visible = 'on';
        end

        % Value changed function: BinaryIndexSlider
        function BinaryIndexSliderValueChanged(app, event)
            %% Resetting main graph
            cla(app.SimilarityMatriceUIAxes_2);
            app.ShowColorCheckBox.Visible = 'off';

            %% Binary filter application
            app.BinaryValue = event.Value;
            app.Binary = imbinarize(app.MatSimi,app.BinaryValue);
            
            %% Image filtered display
            if ~ismissing(app.MedianValue)
                MedianFilterIndexSpinnerValueChanged(app, event);
            else
                %% Filter display
                app.MedianFilterIndexSpinner.Visible = 'on';
                app.MedianFilterIndexLabel.Visible = 'on';
                imagesc(app.SimilarityMatriceUIAxes_2,'CData',app.Binary);
                ylim(app.SimilarityMatriceUIAxes_2,'tight');
                xlim(app.SimilarityMatriceUIAxes_2,'tight');
            end
        end

        % Button pushed function: DisplayContourButton
        function DisplayContourButtonPushed(app, event)
            %% Resetting main graph
            cla(app.SimilarityMatriceUIAxes_2);
            app.ShowColorCheckBox.Value = 0;
            
            %% Calculates contour
            addpath('Personal Functions/Image Processing/')
            app.Contour = bwperim(app.Morph);

            %% Image contour display
            imagesc(app.SimilarityMatriceUIAxes_2,'CData',app.Contour);
            ylim(app.SimilarityMatriceUIAxes_2,'tight');
            xlim(app.SimilarityMatriceUIAxes_2,'tight');

            %% Filter display
            app.ShowColorCheckBox.Visible = 'on';
        end

        % Value changed function: ShowColorCheckBox
        function ShowColorCheckBoxValueChanged(app, event)
            %% Resetting main graph
            cla(app.SimilarityMatriceUIAxes_2);

            addpath('Personal Function/Image Processing/');
            
            value = app.ShowColorCheckBox.Value;
            if value == 1
                app.MatSimiColored = showColor(app.MatSimi, app.Contour, 10, 0.9, 0.6);
                imagesc(app.SimilarityMatriceUIAxes_2,'CData',app.MatSimiColored);
            else
                imagesc(app.SimilarityMatriceUIAxes_2,'CData',app.MatSimi);
            end
            ylim(app.SimilarityMatriceUIAxes_2,'tight');
            xlim(app.SimilarityMatriceUIAxes_2,'tight');

            app.DownloadMatriceButton_2.Visible = 'on';
        end

        % Button pushed function: DownloadMatriceButton_2
        function DownloadMatriceButton_2Pushed(app, event)
            %% Download Similarity Matrice
            if ~ismissing(app.MatSimiColored)
                name = erase(string(app.Filename_2),'.png');
                groupe = extractBefore(name, " -");
                mkdir('Similarity Matrice/',groupe);
                dossier = append('Similarity Matrice/',groupe,'/');
                imwrite(app.MatSimiColored, append(dossier,name,' (colored).png'));
            end
        end

        % Button pushed function: ExpandButton
        function ExpandButtonPushed(app, event)
            if ~ismissing(app.MatSimiColored)
                figure('Name', 'Similarity Matrice colored');
                imshow(app.MatSimiColored);
            elseif ~ismissing(app.MatSimi)
                figure('Name', 'Similarity Matrice');
                imshow(app.MatSimi);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimilarityMatriceMakerTab = uitab(app.TabGroup);
            app.SimilarityMatriceMakerTab.Title = 'Similarity Matrice Maker';
            app.GridLayout = uigridlayout(app.SimilarityMatriceMakerTab);
            app.InputsPanel = uipanel(app.GridLayout);
            app.InputsPanel.Title = 'Inputs';
            app.GridLayout2 = uigridlayout(app.InputsPanel);
            app.ImportSignalButton = uibutton(app.GridLayout2, 'push');
            app.ImportSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSignalButtonPushed, true);
            app.ImportSignalButton.Icon = fullfile(pathToMLAPP, 'Image', 'file.png');
            app.ImportSignalButton.Text = 'Import';
            app.SongsBPMButton = uibutton(app.GridLayout2, 'push');
            app.SongsBPMButton.ButtonPushedFcn = createCallbackFcn(app, @SongsBPMButtonPushed, true);
            app.SongsBPMButton.Visible = 'off';
            app.SongsBPMButton.Text = 'Song''s BPM';
            app.CopyBPMButton = uibutton(app.GridLayout2, 'push');
            app.CopyBPMButton.ButtonPushedFcn = createCallbackFcn(app, @CopyBPMButtonPushed, true);
            app.CopyBPMButton.Icon = fullfile(pathToMLAPP, 'Image', 'copier.png');
            app.CopyBPMButton.Visible = 'off';
            app.CopyBPMButton.Text = '';
            app.SimilarityMatrixButton = uibutton(app.GridLayout2, 'push');
            app.SimilarityMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @SimilarityMatrixButtonPushed, true);
            app.SimilarityMatrixButton.Visible = 'off';
            app.SimilarityMatrixButton.Text = 'Similarity Matrix';
            app.AudioFileNameEditFieldLabel = uilabel(app.GridLayout2);
            app.AudioFileNameEditFieldLabel.Text = 'Audio File Name';
            app.AudioFileNameEditField = uieditfield(app.GridLayout2, 'text');
            app.BPMValueEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BPMValueEditField.ValueChangedFcn = createCallbackFcn(app, @BPMValueEditFieldValueChanged, true);
            app.BPMValueEditField.Visible = 'off';
            app.ListenSignalButton = uibutton(app.GridLayout2, 'push');
            app.ListenSignalButton.ButtonPushedFcn = createCallbackFcn(app, @ListenSignalButtonPushed, true);
            app.ListenSignalButton.Icon = fullfile(pathToMLAPP, 'Image', 'play.png');
            app.ListenSignalButton.Visible = 'off';
            app.ListenSignalButton.Text = 'Listen';
            app.StartingPointSlider = uislider(app.GridLayout2);
            app.StartingPointSlider.ValueChangingFcn = createCallbackFcn(app, @StartingPointSliderValueChanging, true);
            app.StartingPointSlider.Visible = 'off';
            app.Image = uiimage(app.GridLayout2);
            app.Image.Visible = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Image', 'loading3.gif');
            app.StartingPointSliderLabel = uilabel(app.GridLayout2);
            app.StartingPointSliderLabel.Visible = 'off';
            app.StartingPointSliderLabel.Text = 'Starting Point';
            app.BPMValueEditFieldLabel = uilabel(app.GridLayout2);
            app.BPMValueEditFieldLabel.Visible = 'off';
            app.BPMValueEditFieldLabel.Text = 'BPM Value';
            app.OutputsPanel = uipanel(app.GridLayout);
            app.OutputsPanel.Title = 'Outputs';
            app.GridLayout3 = uigridlayout(app.OutputsPanel);
            app.SimilarityMatriceUIAxes = uiaxes(app.GridLayout3);
            title(app.SimilarityMatriceUIAxes, 'Similarity Matrice')
            zlabel(app.SimilarityMatriceUIAxes, 'Z')
            app.SimilarityMatriceUIAxes.Visible = 'off';
            colormap(app.SimilarityMatriceUIAxes, 'gray')
            app.SignalUIAxes = uiaxes(app.GridLayout3);
            title(app.SignalUIAxes, 'Signal')
            xlabel(app.SignalUIAxes, 'Time [in s]')
            ylabel(app.SignalUIAxes, 'Amplitude')
            zlabel(app.SignalUIAxes, 'Z')
            app.SignalUIAxes.Visible = 'off';
            app.DownloadMatriceButton = uibutton(app.GridLayout3, 'push');
            app.DownloadMatriceButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadMatriceButtonPushed, true);
            app.DownloadMatriceButton.Icon = fullfile(pathToMLAPP, 'Image', 'save.png');
            app.DownloadMatriceButton.Visible = 'off';
            app.DownloadMatriceButton.Text = '';
            app.ImportMatriceButton = uibutton(app.GridLayout3, 'push');
            app.ImportMatriceButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMatriceButtonPushed, true);
            app.ImportMatriceButton.Icon = fullfile(pathToMLAPP, 'Image', 'import1.png');
            app.ImportMatriceButton.Text = '';
            app.ChorusDetectorTab = uitab(app.TabGroup);
            app.ChorusDetectorTab.Title = 'Chorus Detector';
            app.GridLayout_2 = uigridlayout(app.ChorusDetectorTab);
            app.InputsPanel_2 = uipanel(app.GridLayout_2);
            app.InputsPanel_2.Title = 'Inputs';
            app.GridLayout4 = uigridlayout(app.InputsPanel_2);
            app.MorphologicallyImageButtonGroup = uibuttongroup(app.GridLayout4);
            app.MorphologicallyImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MorphologicallyImageButtonGroupSelectionChanged, true);
            app.MorphologicallyImageButtonGroup.Title = 'Morphologically Image';
            app.MorphologicallyImageButtonGroup.Visible = 'off';
            app.OpenButton = uiradiobutton(app.MorphologicallyImageButtonGroup);
            app.OpenButton.Text = 'Open';
            app.OpenButton.Value = true;
            app.CloseButton = uiradiobutton(app.MorphologicallyImageButtonGroup);
            app.CloseButton.Text = 'Close';
            app.DisplayContourButton = uibutton(app.GridLayout4, 'push');
            app.DisplayContourButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayContourButtonPushed, true);
            app.DisplayContourButton.Visible = 'off';
            app.DisplayContourButton.Text = 'Display Contour';
            app.ShowColorCheckBox = uicheckbox(app.GridLayout4);
            app.ShowColorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowColorCheckBoxValueChanged, true);
            app.ShowColorCheckBox.Visible = 'off';
            app.ShowColorCheckBox.Text = 'Show Color';
            app.MedianFilterIndexSpinner = uispinner(app.GridLayout4);
            app.MedianFilterIndexSpinner.Limits = [1 9];
            app.MedianFilterIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @MedianFilterIndexSpinnerValueChanged, true);
            app.MedianFilterIndexSpinner.Visible = 'off';
            app.MedianFilterIndexSpinner.Value = 3;
            app.AudioFileNameEditField_2Label = uilabel(app.GridLayout4);
            app.AudioFileNameEditField_2Label.Text = 'Audio File Name';
            app.ImageFileNameEditField = uieditfield(app.GridLayout4, 'text');
            app.ImportImageButton = uibutton(app.GridLayout4, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Icon = fullfile(pathToMLAPP, 'Image', 'file.png');
            app.ImportImageButton.Text = 'Import';
            app.BinaryIndexSlider = uislider(app.GridLayout4);
            app.BinaryIndexSlider.Limits = [0 1];
            app.BinaryIndexSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryIndexSliderValueChanged, true);
            app.BinaryIndexSlider.Tag = 'Binary Index';
            app.BinaryIndexSlider.Visible = 'off';
            app.BinaryIndexSlider.Value = 0.5;
            app.BinaryIndexLabel = uilabel(app.GridLayout4);
            app.BinaryIndexLabel.Visible = 'off';
            app.BinaryIndexLabel.Text = 'Binary Index';
            app.MedianFilterIndexLabel = uilabel(app.GridLayout4);
            app.MedianFilterIndexLabel.Visible = 'off';
            app.MedianFilterIndexLabel.Text = 'Median Filter Index';
            app.OutputsPanel_2 = uipanel(app.GridLayout_2);
            app.OutputsPanel_2.Title = 'Outputs';
            app.GridLayout3_2 = uigridlayout(app.OutputsPanel_2);
            app.SimilarityMatriceUIAxes_2 = uiaxes(app.GridLayout3_2);
            title(app.SimilarityMatriceUIAxes_2, 'Similarity Matrice')
            zlabel(app.SimilarityMatriceUIAxes_2, 'Z')
            app.SimilarityMatriceUIAxes_2.Visible = 'off';
            colormap(app.SimilarityMatriceUIAxes_2, 'gray')
            app.DownloadMatriceButton_2 = uibutton(app.GridLayout3_2, 'push');
            app.DownloadMatriceButton_2.ButtonPushedFcn = createCallbackFcn(app, @DownloadMatriceButton_2Pushed, true);
            app.DownloadMatriceButton_2.Icon = fullfile(pathToMLAPP, 'Image', 'save.png');
            app.DownloadMatriceButton_2.Visible = 'off';
            app.DownloadMatriceButton_2.Text = '';
            app.ExpandButton = uibutton(app.GridLayout3_2, 'push');
            app.ExpandButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandButtonPushed, true);
            app.ExpandButton.Icon = fullfile(pathToMLAPP, 'Image', 'agrandir.png');
            app.ExpandButton.Visible = 'off';
            app.ExpandButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimilarityMatriceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end